classdef InterfaceSimulink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RafrachirButton             matlab.ui.control.Button
        MassemesuregEditField       matlab.ui.control.NumericEditField
        MassemesuregEditFieldLabel  matlab.ui.control.Label
        EntreMassegEditField        matlab.ui.control.NumericEditField
        EntreMasseKgLabel           matlab.ui.control.Label
        ArrtersimulationButton      matlab.ui.control.Button
        DmarrersimulationButton     matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DmarrersimulationButton
        function DmarrersimulationButtonPushed(app, event)
            set_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationCommand', 'start')
        end

        % Button pushed function: ArrtersimulationButton
        function ArrtersimulationButtonPushed(app, event)
            set_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationCommand', 'stop')
        end

        % Value changed function: EntreMassegEditField
        function EntreMassegEditFieldValueChanged(app, event)
            nouvelle_masse = app.EntreMassegEditField.Value;
            valeur_texte = num2str(nouvelle_masse);
            set_param('Simulation_balanceversionajour2024_avec_statespace/Masse (g)', 'value', valeur_texte);
        end

        % Button pushed function: RafrachirButton
        function RafrachirButtonPushed(app, event)

        end

        % Value changed function: MassemesuregEditField
        function MassemesuregEditFieldValueChanged(app, event)
            value = app.MassemesuregEditField.Value;
            status = get_param('Simulation_balanceversionajour2024_avec_statespace','SimulationStatus');
            if strcmp(status, 'running')
                rto =  get_param('Simulation_balanceversionajour2024_avec_statespace/Mesure de masse (g)','RuntimeObject');
                if ~isempty(rto)
                    valeur_lue = rto.InputPort(1).Data;

                    app.MassemesuregEditField.Value = double(valeur_lue);
                end
            else
                uialert(app.UIFigure, 'La simulation doit être en marche pour lire la masse.', 'Erreur');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DmarrersimulationButton = uibutton(app.UIFigure, 'push');
            app.DmarrersimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrersimulationButtonPushed, true);
            app.DmarrersimulationButton.Text = 'Démarrer simulation';
            app.ArrtersimulationButton = uibutton(app.UIFigure, 'push');
            app.ArrtersimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ArrtersimulationButtonPushed, true);
            app.ArrtersimulationButton.Text = 'Arrêter simulation';
            app.EntreMasseKgLabel = uilabel(app.UIFigure);
            app.EntreMasseKgLabel.Text = 'Entrée Masse (g) ';
            app.EntreMassegEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntreMassegEditField.ValueChangedFcn = createCallbackFcn(app, @EntreMassegEditFieldValueChanged, true);
            app.MassemesuregEditFieldLabel = uilabel(app.UIFigure);
            app.MassemesuregEditFieldLabel.Text = 'Masse mesurée (g) ';
            app.MassemesuregEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassemesuregEditField.ValueChangedFcn = createCallbackFcn(app, @MassemesuregEditFieldValueChanged, true);
            app.MassemesuregEditField.Editable = 'off';
            app.RafrachirButton = uibutton(app.UIFigure, 'push');
            app.RafrachirButton.ButtonPushedFcn = createCallbackFcn(app, @RafrachirButtonPushed, true);
            app.RafrachirButton.Text = 'Rafraîchir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceSimulink

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end