classdef InterfaceSimulinkApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        KiCouEditField             matlab.ui.control.Spinner
        KicourantSpinnerLabel      matlab.ui.control.Label
        KpCouEditField             matlab.ui.control.Spinner
        KpcourantSpinnerLabel      matlab.ui.control.Label
        KdPosEditField             matlab.ui.control.Spinner
        KdpositionSpinnerLabel     matlab.ui.control.Label
        KiPosEditField             matlab.ui.control.Spinner
        KipositionSpinnerLabel     matlab.ui.control.Label
        KpPosEditField             matlab.ui.control.Spinner
        KppositionSpinnerLabel     matlab.ui.control.Label
        MassemesureEditField       matlab.ui.control.NumericEditField
        MassemesureEditFieldLabel  matlab.ui.control.Label
        EntreMasseEditField        matlab.ui.control.NumericEditField
        EntreMasseEditFieldLabel   matlab.ui.control.Label
        RafrachirButton            matlab.ui.control.Button
        TareButton                 matlab.ui.control.Button
        EffacergraphiqueButton     matlab.ui.control.Button
        ArrtersimulationButton     matlab.ui.control.Button
        DmarrersimulationButton    matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PlotTimer                   
        LiveLine                    
        TimeOffset = 0             
        TareValue = 0
    end
    
    methods (Access = private)
        
        function GainValueChanged(app)
            try
        assignin('base', 'kp_pos', app.KpPosEditField.Value);
        assignin('base', 'ki_pos', app.KiPosEditField.Value);
        assignin('base', 'kd_pos', app.KdPosEditField.Value);
        
        assignin('base', 'kp_courant', app.KpCouEditField.Value);
        assignin('base', 'ki_courant', app.KiCouEditField.Value);
        
        status = get_param('Simulation_balanceversionajour2024_avec_statespace','SimulationStatus');
        if strcmp(status, 'running')
            set_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationCommand', 'update');
        end
    catch ME
        disp(['Erreur : ', ME.message]);
    end
end
        
function updatePlot(app)
           status = get_param('Simulation_balanceversionajour2024_avec_statespace','SimulationStatus');
    
    if strcmp(status, 'running')
        try
            t_simulink = get_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationTime');
            t_continu = app.TimeOffset + t_simulink;
            
            if t_simulink >= 0.15
                % ATTENTION: Ton ami pointe sur "Scope10". Assure-toi que c'est le bon nom dans ton Simulink !
                rto = get_param('Simulation_balanceversionajour2024_avec_statespace/Scope10','RuntimeObject');
                
                if ~isempty(rto)
                    valeur_brute = double(rto.InputPort(1).Data);
                    valeur_affichee = valeur_brute - app.TareValue;
                    
                    app.LiveLine.XData = [app.LiveLine.XData, t_continu];
                    app.LiveLine.YData = [app.LiveLine.YData, valeur_affichee];
                    app.MassemesuregEditField.Value = valeur_affichee;
                    
                    if t_continu > app.UIAxes.XLim(2)
                        app.UIAxes.XLim = [0, t_continu + 2];
                    end
                    drawnow limitrate; 
                end
            end
        catch
        end
    elseif strcmp(status, 'stopped')
        if isvalid(app.PlotTimer) && strcmp(app.PlotTimer.Running, 'on')
            stop(app.PlotTimer);
        end
    end
end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DmarrersimulationButton
        function DmarrersimulationButtonPushed(app, event)
            valeur_masse = num2str(app.EntreMasseEditField.Value);
set_param('Simulation_balanceversionajour2024_avec_statespace/Masse (g)', 'Value', valeur_masse);
evalin('base', 'Initialisation_simulation'); 
app.GainValueChanged(); % On appelle la fonction des gains

app.TimeOffset = 0;
app.TareValue = 0; 
if isempty(app.LiveLine) || ~isvalid(app.LiveLine)
    app.LiveLine = plot(app.UIAxes, NaN, NaN, 'b-', 'LineWidth', 1.5);
end
app.LiveLine.XData = [];
app.LiveLine.YData = [];
app.UIAxes.XLim = [0 5]; 

set_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationCommand', 'start');

if isempty(app.PlotTimer) || ~isvalid(app.PlotTimer)
    app.PlotTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @(~,~)app.updatePlot());
end
if strcmp(app.PlotTimer.Running, 'off')
    start(app.PlotTimer);
end
        end

        % Button pushed function: ArrtersimulationButton
        function ArrtersimulationButtonPushed(app, event)
            set_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationCommand', 'stop');
if ~isempty(app.PlotTimer) && isvalid(app.PlotTimer)
    stop(app.PlotTimer);
end
        end

        % Button pushed function: RafrachirButton
        function RafrachirButtonPushed(app, event)
            t_actuel = get_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationTime');
app.TimeOffset = app.TimeOffset + t_actuel;
set_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationCommand', 'stop');

nouvelle_masse = app.EntreMasseEditField.Value;
set_param('Simulation_balanceversionajour2024_avec_statespace/Masse (g)', 'Value', num2str(nouvelle_masse));

evalin('base', 'Initialisation_simulation'); 
app.GainValueChanged();

set_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationCommand', 'start');

if ~isempty(app.PlotTimer) && isvalid(app.PlotTimer)
    if strcmp(app.PlotTimer.Running, 'off')
        start(app.PlotTimer);
    end
end
        end

        % Button pushed function: TareButton
        function TareButtonPushed(app, event)
            valeur_actuelle = app.MassemesureEditField.Value;
app.TareValue = app.TareValue + valeur_actuelle;
app.LiveLine.YData = app.LiveLine.YData - valeur_actuelle;
app.MassemesureEditField.Value = 0;
        end

        % Button pushed function: EffacergraphiqueButton
        function EffacergraphiqueButtonPushed(app, event)
            app.LiveLine.XData = [];
app.LiveLine.YData = [];
app.UIAxes.XLim = [0 5];
        end

        % Value changed function: KdPosEditField, KiCouEditField, 
        % ...and 3 other components
        function KpPosEditFieldValueChanged(app, event)
            value = app.KiCouEditField.Value;
            app.GainValueChanged();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            if ~isempty(app.PlotTimer) && isvalid(app.PlotTimer)
    stop(app.PlotTimer);
    delete(app.PlotTimer);
end
delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DmarrersimulationButton = uibutton(app.UIFigure, 'push');
            app.DmarrersimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrersimulationButtonPushed, true);
            app.DmarrersimulationButton.Text = 'Démarrer simulation';
            app.ArrtersimulationButton = uibutton(app.UIFigure, 'push');
            app.ArrtersimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ArrtersimulationButtonPushed, true);
            app.ArrtersimulationButton.Text = 'Arrêter simulation';
            app.EffacergraphiqueButton = uibutton(app.UIFigure, 'push');
            app.EffacergraphiqueButton.ButtonPushedFcn = createCallbackFcn(app, @EffacergraphiqueButtonPushed, true);
            app.EffacergraphiqueButton.Text = 'Effacer graphique';
            app.TareButton = uibutton(app.UIFigure, 'push');
            app.TareButton.ButtonPushedFcn = createCallbackFcn(app, @TareButtonPushed, true);
            app.TareButton.Text = 'Tare';
            app.RafrachirButton = uibutton(app.UIFigure, 'push');
            app.RafrachirButton.ButtonPushedFcn = createCallbackFcn(app, @RafrachirButtonPushed, true);
            app.RafrachirButton.Text = 'Rafraîchir';
            app.EntreMasseEditFieldLabel = uilabel(app.UIFigure);
            app.EntreMasseEditFieldLabel.Text = 'Entrée Masse';
            app.EntreMasseEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassemesureEditFieldLabel = uilabel(app.UIFigure);
            app.MassemesureEditFieldLabel.Text = 'Masse mesurée';
            app.MassemesureEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassemesureEditField.Editable = 'off';
            app.KppositionSpinnerLabel = uilabel(app.UIFigure);
            app.KppositionSpinnerLabel.Text = 'Kp position';
            app.KpPosEditField = uispinner(app.UIFigure);
            app.KpPosEditField.Step = 0.1;
            app.KpPosEditField.ValueChangedFcn = createCallbackFcn(app, @KpPosEditFieldValueChanged, true);
            app.KpPosEditField.Value = 2.325;
            app.KipositionSpinnerLabel = uilabel(app.UIFigure);
            app.KipositionSpinnerLabel.Text = 'Ki position';
            app.KiPosEditField = uispinner(app.UIFigure);
            app.KiPosEditField.Step = 0.1;
            app.KiPosEditField.ValueChangedFcn = createCallbackFcn(app, @KpPosEditFieldValueChanged, true);
            app.KiPosEditField.Value = -27.5;
            app.KdpositionSpinnerLabel = uilabel(app.UIFigure);
            app.KdpositionSpinnerLabel.Text = 'Kd position';
            app.KdPosEditField = uispinner(app.UIFigure);
            app.KdPosEditField.Step = 0.1;
            app.KdPosEditField.ValueChangedFcn = createCallbackFcn(app, @KpPosEditFieldValueChanged, true);
            app.KdPosEditField.Value = -0.207;
            app.KpcourantSpinnerLabel = uilabel(app.UIFigure);
            app.KpcourantSpinnerLabel.Text = 'Kp courant';
            app.KpCouEditField = uispinner(app.UIFigure);
            app.KpCouEditField.Step = 0.1;
            app.KpCouEditField.ValueChangedFcn = createCallbackFcn(app, @KpPosEditFieldValueChanged, true);
            app.KpCouEditField.Value = -0.575;
            app.KicourantSpinnerLabel = uilabel(app.UIFigure);
            app.KicourantSpinnerLabel.Text = 'Ki courant';
            app.KiCouEditField = uispinner(app.UIFigure);
            app.KiCouEditField.Step = 0.1;
            app.KiCouEditField.ValueChangedFcn = createCallbackFcn(app, @KpPosEditFieldValueChanged, true);
            app.KiCouEditField.Value = -325;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceSimulinkApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end